classdef expended < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MONTHEditField                matlab.ui.control.EditField
        MONTHLabel                    matlab.ui.control.Label
        CALCULATEButton               matlab.ui.control.Button
        DatePicker                    matlab.ui.control.DatePicker
        DatePickerLabel               matlab.ui.control.Label
        TOTALEXPENDEDEditField        matlab.ui.control.NumericEditField
        TOTALEXPENDEDEditFieldLabel   matlab.ui.control.Label
        UNIFORMEditField              matlab.ui.control.NumericEditField
        UNIFORMEditFieldLabel         matlab.ui.control.Label
        SAVINGSEditField              matlab.ui.control.NumericEditField
        SAVINGSEditFieldLabel         matlab.ui.control.Label
        MISCELLANEOUSEditField        matlab.ui.control.NumericEditField
        MISCELLANEOUSLabel            matlab.ui.control.Label
        TUITIONANDFEESEditField       matlab.ui.control.NumericEditField
        TUITIONANDFEESLabel           matlab.ui.control.Label
        BOOKSEditField                matlab.ui.control.NumericEditField
        BOOKSEditFieldLabel           matlab.ui.control.Label
        FOODEditField                 matlab.ui.control.NumericEditField
        FOODEditFieldLabel            matlab.ui.control.Label
        TRANSPORTATIONEditField       matlab.ui.control.NumericEditField
        TRANSPORTATIONEditFieldLabel  matlab.ui.control.Label
        HOUSEFEESEditField            matlab.ui.control.NumericEditField
        HOUSEFEESEditFieldLabel       matlab.ui.control.Label
        SAVEButton                    matlab.ui.control.Button
        BREAKDOWNLabel                matlab.ui.control.Label
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MONTHEditField
        function MONTHEditFieldValueChanged(app, event)
            month_expended = app.MONTHEditField.Value;
            
        end

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            month_expended = app.MONTHEditField.Value;
            
            house_fees = app.HOUSEFEESEditField.Value;
            transportation = app.TRANSPORTATIONEditField.Value;
            food = app.FOODEditField.Value;
            books = app.BOOKSEditField.Value;
            uniform = app.UNIFORMEditField.Value;
            tuition_and_fees = app.TUITIONANDFEESEditField.Value;
            miscellaneous = app.MISCELLANEOUSEditField.Value;

            total_expended = house_fees + transportation + food + books + uniform + tuition_and_fees + miscellaneous;
            app.TOTALEXPENDEDEditField.Value = total_expended;

            received_summary = readmatrix('AllowanceTracker_Received.csv');

            if month_expended == "January"
                total_received = received_summary(1,9);
            elseif month_expended == "February"
                total_received = received_summary(2,9);
            elseif month_expended == "March"
                total_received = received_summary(3,9);            
            elseif month_expended == "April"
                total_received = received_summary(4,9);
            elseif month_expended == "May"
                total_received = received_summary(5,9);
            elseif month_expended == "June"
                total_received = received_summary(6,9);
            elseif month_expended == "July"
                total_received = received_summary(7,9);
            elseif month_expended == "August"
                total_received = received_summary(8,9);
            elseif month_expended == "September"
                total_received = received_summary(9,9);
            elseif month_expended == "October"
                total_received = received_summary(10,9);
            elseif month_expended == "November"
                total_received = received_summary(11,9);
            elseif month_expended == "December"
                total_received = received_summary(12,9);
            end

            total_saved = (total_received - total_expended);
            app.SAVINGSEditField.Value = total_saved;
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            month_expended = app.MONTHEditField.Value;
            
            house_fees = app.HOUSEFEESEditField.Value;
            transportation = app.TRANSPORTATIONEditField.Value;
            food = app.FOODEditField.Value;
            books = app.BOOKSEditField.Value;
            uniform = app.UNIFORMEditField.Value;
            tuition_and_fees = app.TUITIONANDFEESEditField.Value;
            miscellaneous = app.MISCELLANEOUSEditField.Value;

            total_expended = house_fees + transportation + food + books + uniform + tuition_and_fees + miscellaneous;
            app.TOTALEXPENDEDEditField.Value = total_expended;

            received_summary = readmatrix('AllowanceTracker_Received.csv');

            if month_expended == "January"
                total_received = received_summary(1,9);
            elseif month_expended == "February"
                total_received = received_summary(2,9);
            elseif month_expended == "March"
                total_received = received_summary(3,9);            
            elseif month_expended == "April"
                total_received = received_summary(4,9);
            elseif month_expended == "May"
                total_received = received_summary(5,9);
            elseif month_expended == "June"
                total_received = received_summary(6,9);
            elseif month_expended == "July"
                total_received = received_summary(7,9);
            elseif month_expended == "August"
                total_received = received_summary(8,9);
            elseif month_expended == "September"
                total_received = received_summary(9,9);
            elseif month_expended == "October"
                total_received = received_summary(10,9);
            elseif month_expended == "November"
                total_received = received_summary(11,9);
            elseif month_expended == "December"
                total_received = received_summary(12,9);
            end

            total_saved = (total_received - total_expended);
            app.SAVINGSEditField.Value = total_saved;

            writematrix('AllowanceTracker_Expended.csv');
            file = fullfile(pwd, 'AllowanceTracker_Expended.csv');
            expended = fopen(file, 'a+');
            fprintf(expended, '%s, %f, %f, %f, %f, %f, %f, %f, %f\n', month_expended, house_fees, transportation, food, books, uniform, tuition_and_fees, miscellaneous, total_expended);
            fclose(expended);
            type(file)

            writematrix('AllowanceTracker_Saved.csv');
            file = fullfile(pwd, 'AllowanceTracker_Saved.csv');
            saved = fopen(file, 'a+');
            fprintf(saved, '%s, %f\n', month_expended, total_saved);
            fclose(saved);
            type(file)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'expended allowance.PNG');
            app.BREAKDOWNLabel = uilabel(app.UIFigure);
            app.BREAKDOWNLabel.Text = 'BREAKDOWN';
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.HOUSEFEESEditFieldLabel = uilabel(app.UIFigure);
            app.HOUSEFEESEditFieldLabel.Text = 'HOUSE FEES ';
            app.HOUSEFEESEditField = uieditfield(app.UIFigure, 'numeric');
            app.TRANSPORTATIONEditFieldLabel = uilabel(app.UIFigure);
            app.TRANSPORTATIONEditFieldLabel.Text = 'TRANSPORTATION ';
            app.TRANSPORTATIONEditField = uieditfield(app.UIFigure, 'numeric');
            app.FOODEditFieldLabel = uilabel(app.UIFigure);
            app.FOODEditFieldLabel.Text = 'FOOD ';
            app.FOODEditField = uieditfield(app.UIFigure, 'numeric');
            app.BOOKSEditFieldLabel = uilabel(app.UIFigure);
            app.BOOKSEditFieldLabel.Text = 'BOOKS ';
            app.BOOKSEditField = uieditfield(app.UIFigure, 'numeric');
            app.TUITIONANDFEESLabel = uilabel(app.UIFigure);
            app.TUITIONANDFEESLabel.Text = 'TUITION AND FEES ';
            app.TUITIONANDFEESEditField = uieditfield(app.UIFigure, 'numeric');
            app.MISCELLANEOUSLabel = uilabel(app.UIFigure);
            app.MISCELLANEOUSLabel.Text = 'MISCELLANEOUS ';
            app.MISCELLANEOUSEditField = uieditfield(app.UIFigure, 'numeric');
            app.SAVINGSEditFieldLabel = uilabel(app.UIFigure);
            app.SAVINGSEditFieldLabel.Text = 'SAVINGS ';
            app.SAVINGSEditField = uieditfield(app.UIFigure, 'numeric');
            app.UNIFORMEditFieldLabel = uilabel(app.UIFigure);
            app.UNIFORMEditFieldLabel.Text = 'UNIFORM ';
            app.UNIFORMEditField = uieditfield(app.UIFigure, 'numeric');
            app.TOTALEXPENDEDEditFieldLabel = uilabel(app.UIFigure);
            app.TOTALEXPENDEDEditFieldLabel.Text = 'TOTAL EXPENDED ';
            app.TOTALEXPENDEDEditField = uieditfield(app.UIFigure, 'numeric');
            app.DatePickerLabel = uilabel(app.UIFigure);
            app.DatePickerLabel.Text = 'Date Picker';
            app.DatePicker = uidatepicker(app.UIFigure);
            app.CALCULATEButton = uibutton(app.UIFigure, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.MONTHLabel = uilabel(app.UIFigure);
            app.MONTHLabel.Text = ' MONTH ';
            app.MONTHEditField = uieditfield(app.UIFigure, 'text');
            app.MONTHEditField.ValueChangedFcn = createCallbackFcn(app, @MONTHEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = expended

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end