classdef received < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MONTHEditField               matlab.ui.control.EditField
        MONTHLabel                   matlab.ui.control.Label
        CALCULATEButton              matlab.ui.control.Button
        TOTALRECEIVEDEditField       matlab.ui.control.NumericEditField
        TOTALRECEIVEDLabel           matlab.ui.control.Label
        MISCELLANEOUSEditField       matlab.ui.control.NumericEditField
        MISCELLANEOUSEditFieldLabel  matlab.ui.control.Label
        SAVEButton                   matlab.ui.control.Button
        UNIFORMEditField             matlab.ui.control.NumericEditField
        UNIFORMEditFieldLabel        matlab.ui.control.Label
        TUITIONANDFEESEditField      matlab.ui.control.NumericEditField
        TUITIONANDFEESLabel          matlab.ui.control.Label
        BOOKSEditField               matlab.ui.control.NumericEditField
        BOOKSEditFieldLabel          matlab.ui.control.Label
        FOODEditField                matlab.ui.control.NumericEditField
        FOODEditFieldLabel           matlab.ui.control.Label
        TRANSPORTATIONEditField      matlab.ui.control.NumericEditField
        TRANSPORTATIONLabel          matlab.ui.control.Label
        HOUSEFEESEditField           matlab.ui.control.NumericEditField
        HOUSEFEESLabel               matlab.ui.control.Label
        BREAKDOWNLabel               matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MONTHEditField
        function MONTHEditFieldValueChanged(app, event)
            month_received = app.MONTHEditField.Value;
            
        end

        % Button pushed function: CALCULATEButton
        function CALCULATEButtonPushed(app, event)
            format long
            
            house_fees = app.HOUSEFEESEditField.Value;
            transportation = app.TRANSPORTATIONEditField.Value;
            food = app.FOODEditField.Value;
            books = app.BOOKSEditField.Value;
            uniform = app.UNIFORMEditField.Value;
            tuition_and_fees = app.TUITIONANDFEESEditField.Value;
            miscellaneous = app.MISCELLANEOUSEditField.Value;

            total_received = house_fees + transportation + food + books + uniform + tuition_and_fees + miscellaneous;

            app.TOTALRECEIVEDEditField.Value = total_received;   
        end

        % Button pushed function: SAVEButton
        function SAVEButtonPushed(app, event)
            format long
            
            month_received = app.MONTHEditField.Value;
            
            house_fees = app.HOUSEFEESEditField.Value;
            transportation = app.TRANSPORTATIONEditField.Value;
            food = app.FOODEditField.Value;
            books = app.BOOKSEditField.Value;
            uniform = app.UNIFORMEditField.Value;
            tuition_and_fees = app.TUITIONANDFEESEditField.Value;
            miscellaneous = app.MISCELLANEOUSEditField.Value;

            total_received = house_fees + transportation + food + books + uniform + tuition_and_fees + miscellaneous;

            app.TOTALRECEIVEDEditField.Value = total_received;  
            
            writematrix('AllowanceTracker_Received.csv');
            file = fullfile(pwd, 'AllowanceTracker_Received.csv');
            received = fopen(file, 'a+');
            fprintf(received, '%s, %f, %f, %f, %f, %f, %f, %f, %f\n', month_received, house_fees, transportation, food, books, uniform, tuition_and_fees, miscellaneous, total_received);
            fclose(received);
            type(file)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'received allowance.PNG');
            app.BREAKDOWNLabel = uilabel(app.UIFigure);
            app.BREAKDOWNLabel.Text = 'BREAKDOWN';
            app.HOUSEFEESLabel = uilabel(app.UIFigure);
            app.HOUSEFEESLabel.Text = 'HOUSE FEES ';
            app.HOUSEFEESEditField = uieditfield(app.UIFigure, 'numeric');
            app.TRANSPORTATIONLabel = uilabel(app.UIFigure);
            app.TRANSPORTATIONLabel.Text = 'TRANSPORTATION ';
            app.TRANSPORTATIONEditField = uieditfield(app.UIFigure, 'numeric');
            app.FOODEditFieldLabel = uilabel(app.UIFigure);
            app.FOODEditFieldLabel.Text = 'FOOD ';
            app.FOODEditField = uieditfield(app.UIFigure, 'numeric');
            app.BOOKSEditFieldLabel = uilabel(app.UIFigure);
            app.BOOKSEditFieldLabel.Text = 'BOOKS ';
            app.BOOKSEditField = uieditfield(app.UIFigure, 'numeric');
            app.TUITIONANDFEESLabel = uilabel(app.UIFigure);
            app.TUITIONANDFEESLabel.Text = 'TUITION AND FEES ';
            app.TUITIONANDFEESEditField = uieditfield(app.UIFigure, 'numeric');
            app.UNIFORMEditFieldLabel = uilabel(app.UIFigure);
            app.UNIFORMEditFieldLabel.Text = 'UNIFORM ';
            app.UNIFORMEditField = uieditfield(app.UIFigure, 'numeric');
            app.SAVEButton = uibutton(app.UIFigure, 'push');
            app.SAVEButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEButtonPushed, true);
            app.SAVEButton.Text = 'SAVE';
            app.MISCELLANEOUSEditFieldLabel = uilabel(app.UIFigure);
            app.MISCELLANEOUSEditFieldLabel.Text = 'MISCELLANEOUS ';
            app.MISCELLANEOUSEditField = uieditfield(app.UIFigure, 'numeric');
            app.TOTALRECEIVEDLabel = uilabel(app.UIFigure);
            app.TOTALRECEIVEDLabel.Text = 'TOTAL RECEIVED ';
            app.TOTALRECEIVEDEditField = uieditfield(app.UIFigure, 'numeric');
            app.CALCULATEButton = uibutton(app.UIFigure, 'push');
            app.CALCULATEButton.ButtonPushedFcn = createCallbackFcn(app, @CALCULATEButtonPushed, true);
            app.CALCULATEButton.Text = 'CALCULATE';
            app.MONTHLabel = uilabel(app.UIFigure);
            app.MONTHLabel.Text = ' MONTH ';
            app.MONTHEditField = uieditfield(app.UIFigure, 'text');
            app.MONTHEditField.ValueChangedFcn = createCallbackFcn(app, @MONTHEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = received

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end