classdef summary < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Image     matlab.ui.control.Image
        UIAxes    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            summary = readtable('AllowanceTracker_Received.csv');
            
            summary.Month = categorical(summary.Month, ["January", "February", "March", "April","May", "June", "July", "August", "September", "October", "November","December"]);
            monthly_input = (summary.Month);

            received_summary = readtable('AllowanceTracker_Received.csv');
            total_received = received_summary.TotalReceived;

            expended_summary = readtable('AllowanceTracker_Expended.csv');
            total_expended = expended_summary.TotalExpended;

            saved_summary = readtable('AllowanceTracker_Saved.csv');
            total_saved = saved_summary.TotalSaved;

            hold(app.UIAxes, "on");
            grid(app.UIAxes, "on");
            title(app.UIAxes, "Monthly Summary of Allowances, Expenses, and Savings");

            bar(app.UIAxes, monthly_input, total_received, "g");
            bar(app.UIAxes, monthly_input, total_expended, "r");
            bar(app.UIAxes, monthly_input, total_saved, "b");
            legend(app.UIAxes,'Total Received', 'Total Expended', 'Total Saved');
            hold(app.UIAxes, "off");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'MONTH')
            ylabel(app.UIAxes, 'AMOUNT')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = 0;
            app.UIAxes.XTickLabel = {'0'};
            app.UIAxes.YTick = [5000 10000 15000 20000 25000];
            app.UIAxes.YTickLabel = {'5000'; '10000'; '15000'; '20000'; '25000'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'graph summary.PNG');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = summary

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end